classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        MethodName      matlab.ui.control.DropDown
        RunBtn          matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        y0SpinnerLabel  matlab.ui.control.Label
        y0Spinner       matlab.ui.control.Spinner
        x0SpinnerLabel  matlab.ui.control.Label
        x0Spinner       matlab.ui.control.Spinner
        v0SpinnerLabel  matlab.ui.control.Label
        v0Spinner       matlab.ui.control.Spinner
        x1SpinnerLabel  matlab.ui.control.Label
        x1Spinner       matlab.ui.control.Spinner
        y1SpinnerLabel  matlab.ui.control.Label
        y1Spinner       matlab.ui.control.Spinner
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        Panel_2         matlab.ui.container.Panel
        Tab_2           matlab.ui.container.Tab
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunBtn
        function RunBtnButtonPushed(app, event)
            methodName = app.MethodName.Value;
            x0 = deg2rad(app.x0Spinner.Value);
            y0 = deg2rad(app.y0Spinner.Value);
            x1 = deg2rad(app.x1Spinner.Value);
            y1 = deg2rad(app.y1Spinner.Value);
            [phi, lambda] = Ortodromical(x0, y0, x1, y1);
            phi_res = zeros(100, 1);
            lambda_res = zeros(100, 1);
            if (methodName == "Маршрутный")
                phi_res = Fly(phi);
                lambda_res = lambda;
            else
                [phi_res, lambda_res] = Curs(phi, lambda);
            end
            phi_res = phi_res(2:end);
            lambda_res = lambda_res(2:end);
            gx = geoaxes(app.Panel_2);
            geoplot(gx,phi_res, lambda_res,'g-*');
            geobasemap(gx,'colorterrain');
            R = 10;
            [x,y,z] = ellipsoid(0,0,0,R,R,R);
            [x_c, y_c] = meshgrid(rad2deg(y0) - 0.1:0.01:rad2deg(y0)+0.1, ...
                rad2deg(x0)-0.1:0.01:rad2deg(x0)+0.1);
            z0 = 1 * cos(x0);
            z_c = (-1/(2 * z0)) * (2 * x0 * x_c + 2 * y0 * y_c - 1);
            %surf(x, y, z);
            %hold on;
            %surf(x_c,y_c,z_c);
            %hold off;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Ортодромия';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Метод';
            app.MethodName = uidropdown(app.UIFigure);
            app.MethodName.Items = {'Маршрутный', 'Курсовой'};
            app.MethodName.Value = 'Маршрутный';
            app.RunBtn = uibutton(app.UIFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnButtonPushed, true);
            app.RunBtn.Text = 'Запуск';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Параметры ОУ';
            app.y0SpinnerLabel = uilabel(app.Panel);
            app.y0SpinnerLabel.Text = 'y0';
            app.y0Spinner = uispinner(app.Panel);
            app.y0Spinner.Value = 55.41;
            app.x0SpinnerLabel = uilabel(app.Panel);
            app.x0SpinnerLabel.Text = 'x0';
            app.x0Spinner = uispinner(app.Panel);
            app.x0Spinner.Value = 20.58;
            app.v0SpinnerLabel = uilabel(app.Panel);
            app.v0SpinnerLabel.Text = 'v0';
            app.v0Spinner = uispinner(app.Panel);
            app.v0Spinner.Value = 100;
            app.x1SpinnerLabel = uilabel(app.Panel);
            app.x1SpinnerLabel.Text = 'x1';
            app.x1Spinner = uispinner(app.Panel);
            app.x1Spinner.Value = 37.89;
            app.y1SpinnerLabel = uilabel(app.Panel);
            app.y1SpinnerLabel.Text = 'y1';
            app.y1Spinner = uispinner(app.Panel);
            app.y1Spinner.Value = 54.88;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Карта';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Карта полета';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Оси';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, 'Ортодромические оси')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end